classdef sstv_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Suggest                     matlab.ui.control.Label
        StopAudioButton             matlab.ui.control.Button
        GenerateButton              matlab.ui.control.Button
        ExportsoundaswavfileButton  matlab.ui.control.Button
        PlayAudioButton             matlab.ui.control.Button
        Step3GenerateSoundfileExportAudioLabel  matlab.ui.control.Label
        ModeDropDown                matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        Step1SelectModeLabel        matlab.ui.control.Label
        EncodeingImagefiletoSSTVsignalLabel  matlab.ui.control.Label
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Step2SelectpngfileGenerateLabel  matlab.ui.control.Label
        ImportImagefileButton       matlab.ui.control.Button
        time_plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        filename        = "";   % filename with File name extension
        filename_only   = "";   % filename without File name extension
        full_path       = "";   % path with filename
        path            = "";   % path without Filename

        audio = "";

        y  = [];
        Fs = [];


        audio_Data = {}; % y resampled
        length_Data = []; % length y
        result_sound = [];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImagefileButton
        function ImportImagefileButtonPushed(app, event)
            % Set File Path 
            [app.filename ,app.path] = uigetfile('*.png');  % Open a png file
            app.full_path = append(app.path ,app.filename);
            drawnow;
            figure(app.UIFigure);


            % Set Filename only
            filename_split = split(app.filename,".");
            app.filename_only = filename_split{1,1};
            
            % Set Path to Editbox and Image
            app.FilePathEditField.Value = app.full_path;
            app.Image.ImageSource       = app.full_path;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Generate wav file
            command = strcat("pysstv"," ","--mode"," ",app.ModeDropDown.Value," ",app.FilePathEditField.Value," ",app.filename_only,".wav");
            system(command);
            

            % Read Image file
            %A = imread(app.full_path);

            % Create Wav file
            %

            % Get Signal
            [app.y, app.Fs] = audioread(append(app.filename_only,".wav"));

            % Plot Signal to UIAxes
            cla(app.time_plot);             % Clear Plot
            app.time_plot.Title.String = append(app.filename_only,".wav");      % Set plot title as filename

            
            app.y = app.y(:,1);
            dt = 1/app.Fs;
            t = 0:dt:(length(app.y)*dt)-dt;
            
            
            plot(app.time_plot,t,app.y); xlabel('Seconds'); ylabel('Amplitude');
            plot(t ,app.y);

            %plot(app.time_plot ,app.Fs ,app.y);
            %{
            %}

            % Plot V2
            %{
            [app.y, app.Fs] = audioread(append(app.filename_only,".wav"));

            window=hamming(512);
            noverlap = 256;
            nfft = 1024;
            [S,F,T,P] = spectrogram(app.y ,window ,noverlap ,nfft ,app.Fs ,'yaxis');
            surf(T,F,10*log10(P),'edgecolor','none'); axis tight;view(0,90);
            colormap("hot");
            set(gca,'clim',[-80 -30]);
            xlable('Temps (Seconds)'); ylabel("Frequences Hz");
            %}
            
            % Plot V3
            %{
            [app.y, app.Fs] = audioread(append(app.filename_only,".wav"));
            
            % Plot Signal to UIAxes
            cla(app.time_plot);             % Clear Plot
            
            plot(app.time_plot ,app.Fs ,app.y);

            %}


        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            % Play audio
            if (~(isempty(app.y)))
                sound(app.y,48000);
            end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ExportsoundaswavfileButton
        function ExportsoundaswavfileButtonPushed(app, event)
            % Export Signal as wav file
            [file, folder] = uiputfile('*.wav');
            if ~isequal(file, 0)  % User did not press Cancel:
                audiowrite(fullfile(folder, file), app.y, app.Fs);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.time_plot = uiaxes(app.UIFigure);
            title(app.time_plot, 'Title')
            xlabel(app.time_plot, 'X')
            ylabel(app.time_plot, 'Y')
            zlabel(app.time_plot, 'Z')
            app.ImportImagefileButton = uibutton(app.UIFigure, 'push');
            app.ImportImagefileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImagefileButtonPushed, true);
            app.ImportImagefileButton.Text = 'Import Image file';
            app.Step2SelectpngfileGenerateLabel = uilabel(app.UIFigure);
            app.Step2SelectpngfileGenerateLabel.Text = 'Step 2 : Select .png file & Generate';
            app.Image = uiimage(app.UIFigure);
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path : ';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.EncodeingImagefiletoSSTVsignalLabel = uilabel(app.UIFigure);
            app.EncodeingImagefiletoSSTVsignalLabel.Text = 'Encodeing Image file to SSTV signal';
            app.Step1SelectModeLabel = uilabel(app.UIFigure);
            app.Step1SelectModeLabel.Text = 'Step 1 : Select Mode';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'MartinM1', 'MartinM2', 'ScottieS1', 'ScottieS2', 'PasokonP3', 'PasokonP5', 'PasokonP7', 'PD90', 'PD120', 'PD160', 'PD180', 'PD240', 'PD290', 'Robot8BW', 'Robot24BW', 'Robot36'};
            app.ModeDropDown.Value = 'Robot36';
            app.Step3GenerateSoundfileExportAudioLabel = uilabel(app.UIFigure);
            app.Step3GenerateSoundfileExportAudioLabel.Text = 'Step 3 : Generate Sound file & Export Audio';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.ExportsoundaswavfileButton = uibutton(app.UIFigure, 'push');
            app.ExportsoundaswavfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsoundaswavfileButtonPushed, true);
            app.ExportsoundaswavfileButton.Text = 'Export sound as wav file';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.StopAudioButton = uibutton(app.UIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.Suggest = uilabel(app.UIFigure);
            app.Suggest.Text = '*';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sstv_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end